classdef MatrixCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        Tab2                           matlab.ui.container.Tab
        MatrixCalculatorPanel          matlab.ui.container.Panel
        ClearResButton                 matlab.ui.control.Button
        ClearAllButton                 matlab.ui.control.Button
        MULLamp                        matlab.ui.control.Lamp
        SUBLamp                        matlab.ui.control.Lamp
        ADDLamp                        matlab.ui.control.Lamp
        ResultPanel                    matlab.ui.container.Panel
        SUBSwitch                      matlab.ui.control.ToggleSwitch
        SUBLabel                       matlab.ui.control.Label
        MULSwitch                      matlab.ui.control.ToggleSwitch
        MULSwitchLabel                 matlab.ui.control.Label
        ADDSwitch                      matlab.ui.control.ToggleSwitch
        ADDSwitchLabel                 matlab.ui.control.Label
        numColSpinner2                 matlab.ui.control.Spinner
        NumberofColumnsSpinnerLabel_2  matlab.ui.control.Label
        numRowSpinner2                 matlab.ui.control.Spinner
        NumberofRowsSpinnerLabel_2     matlab.ui.control.Label
        Matrix2Label                   matlab.ui.control.Label
        Matrix1Label                   matlab.ui.control.Label
        numColSpinner                  matlab.ui.control.Spinner
        NumberofColumnsSpinnerLabel    matlab.ui.control.Label
        numRowSpinner                  matlab.ui.control.Spinner
        NumberofRowsSpinnerLabel       matlab.ui.control.Label
        DisplayPanel2                  matlab.ui.container.Panel
        CalculateButton                matlab.ui.control.Button
        DisplayButton                  matlab.ui.control.Button
        DisplayPanel1                  matlab.ui.container.Panel
    end

    
    properties (Access = private)
        numRowSp1
        numColSp1
        numRowSp2
        numColSp2
        children1
        children2
        matrixStrArray1
        matrixStrArray2
        operation
        matrixA
        matrixB
        resRow
        resCol
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.numRowSp1 = 1;
            app.numColSp1 = 1;
            app.numRowSp2 = 1;
            app.numColSp2 = 1;
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65'; % gray
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end

            if strcmp(app.DisplayPanel1.Title, "Matrix 1")
                app.DisplayPanel1.Title = '';
                app.DisplayPanel2.Title = '';
            end
            
            % Matrix 1
            paddingX = 5;
            paddingY = 5;

            numRows1 = app.numRowSp1;
            numCols1 = app.numColSp1;
            
            panelWidth1 = app.DisplayPanel1.Position(3);
            panelHeight1 = app.DisplayPanel1.Position(4);

            fieldWidth1 = (panelWidth1 - (paddingX * (numCols1 - 1))) / numCols1;
            fieldHeight1 = (panelHeight1 - (paddingY * (numRows1 - 1))) / numRows1;
 
            for row1 = 1:numRows1
                for col1 = 1:numCols1
                    field = uieditfield(app.DisplayPanel1,'numeric', 'Editable', 'on', 'HorizontalAlignment', 'center');

                    fieldX1 = (col1 - 1) * (fieldWidth1 + paddingX);
                    fieldY1 = panelHeight1 - row1 * (fieldHeight1 + paddingY);

                    if row1 == 1
                        fieldY1 = panelHeight1 - row1 * fieldHeight1;
                    end
                    
                    field.Tag = sprintf('A%d%d', row1, col1);
                    field.Position = [fieldX1, fieldY1, fieldWidth1, fieldHeight1];
                    pause(0.05)
                end
            end

            % Matrix 2
            numRows2 = app.numRowSp2;
            numCols2 = app.numColSp2;

            panelWidth2 = app.DisplayPanel2.Position(3);
            panelHeight2 = app.DisplayPanel2.Position(4);

            fieldWidth2 = (panelWidth2 - (paddingX * (numCols2 - 1))) / numCols2;
            fieldHeight2 = (panelHeight2 - (paddingY * (numRows2 - 1))) / numRows2;
 
            for row2 = 1:numRows2
                for col2 = 1:numCols2
                    field = uieditfield(app.DisplayPanel2,'numeric', 'Editable', 'on', 'HorizontalAlignment', 'center');

                    fieldX2 = (col2 - 1) * (fieldWidth2 + paddingX);
                    fieldY2 = panelHeight2 - row2 * (fieldHeight2 + paddingY);

                    if row2 == 1
                        fieldY2 = panelHeight2 - row2 * fieldHeight2;
                    end
                    
                    field.Tag = sprintf('B%d%d', row2, col2);
                    field.Position = [fieldX2, fieldY2, fieldWidth2, fieldHeight2];
                    pause(.05)
                end
            end

            app.children1 = app.DisplayPanel1.Children;

            for idx = 1:length(app.children1)
                childIdx1 = app.children1(idx).Tag;
                disp(['The tag of Matrix 1 component ' num2str(idx) ' is: ' childIdx1]);
            end

            app.children2 = app.DisplayPanel2.Children;

            for idx = 1:length(app.children2)
                childIdx2 = app.children2(idx).Tag;
                disp(['The tag of Matrix 2 component ' num2str(idx) ' is: ' childIdx2]);
            end

            % Clear All Button
            app.ClearAllButton.Enable = 'on';

            %Lamp
            if (app.numRowSp1 == app.numRowSp2) && (app.numColSp1 == app.numColSp2)
                app.ADDLamp.Color = 'g';
                app.SUBLamp.Color = 'g';
                app.ADDSwitch.Enable = "on";
                app.SUBSwitch.Enable = "on";
            else
                app.ADDLamp.Color = 'r';
                app.SUBLamp.Color = 'r';
                app.ADDSwitch.Value = 'Off';
                app.SUBSwitch.Value = 'Off';
            end
            
            if (app.numColSp1 == app.numRowSp2 || app.numColSp1 == 1 && app.numRowSp1 == 1 || app.numColSp2 == 1 && app.numRowSp2 == 1)
                app.MULLamp.Color = 'g';
                app.MULSwitch.Enable = "on";
            else
                app.MULLamp.Color = 'r';
                app.MULSwitch.Value = 'Off';
            end

            % Calculate Button
            if (strcmp(app.MULSwitch.Value, 'Off')) && (strcmp(app.SUBSwitch.Value, 'Off') && strcmp(app.ADDSwitch.Value, 'Off'))
                app.CalculateButton.Enable = "off";
            else
                app.CalculateButton.Enable = "on";
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Matrix 1
            app.matrixStrArray1 = '';
            app.matrixStrArray2 = '';
            matrix1 = zeros(app.numRowSp1, app.numColSp1);
            matrix2 = zeros(app.numRowSp2, app.numColSp2);

            for row1 = 1:app.numRowSp1
                for col1 = 1:app.numColSp1
                    idx1 = sprintf('A%d%d', row1, col1);
                    uiComponent1 = findobj(app.UIFigure, 'Tag', idx1);
                    currentValue1 = uiComponent1.Value;
                    matrix1(row1, col1) = currentValue1;
                end
            end

            % Matrix 2
            for row2 = 1:app.numRowSp2
                for col2 = 1:app.numColSp2
                    idx2 = sprintf('B%d%d', row2, col2);
                    uiComponent2 = findobj(app.UIFigure, 'Tag', idx2);
                    currentValue2 = uiComponent2.Value;
                    matrix2(row2, col2) = currentValue2;
                end
            end
            
            % for cli checking
            disp(['testing1 ' app.matrixStrArray1])
            disp(['testing2 ' app.matrixStrArray2])

            app.matrixA = matrix1;
            app.matrixB = matrix2;
            
            % for cli checking
            disp(app.matrixA)
            disp(app.matrixB)


            % Matrices Calculation
            if strcmp(app.ADDSwitch.Value, 'On')
                    if (app.numRowSp1 == app.numRowSp2) && (app.numColSp1 == app.numColSp2)
                        sumOfMatrices = app.matrixA + app.matrixB;
                        resultMatrix = sumOfMatrices;
                        [app.resRow, app.resCol] = size(sumOfMatrices);
                    else
                        msgbox('Arrays have incompatible sizes for this operation.', 'Error: Incompatible Sizes')
                        app.ADDSwitch.Value = 'Off';
                        app.SUBSwitch.Value = 'Off';
                        app.MULSwitch.Value = 'Off';
                        app.CalculateButton = 'Off';
                    end
            elseif strcmp(app.SUBSwitch.Value, 'On')
                    if (app.numRowSp1 == app.numRowSp2) && (app.numColSp1 == app.numColSp2)
                        difOfMatrices = app.matrixA - app.matrixB;
                        resultMatrix = difOfMatrices;
                        [app.resRow, app.resCol] = size(difOfMatrices);
                    else
                        msgbox('Arrays have incompatible sizes for this operation.', 'Error: Incompatible Sizes')
                        app.ADDSwitch.Value = 'Off';
                        app.SUBSwitch.Value = 'Off';
                        app.MULSwitch.Value = 'Off';
                        app.CalculateButton = 'Off';
                    end
            elseif strcmp(app.MULSwitch.Value, 'On')
                    if (app.numColSp1 == app.numRowSp2 || app.numColSp1 == 1 && app.numRowSp1 == 1 || app.numColSp2 == 1 && app.numRowSp2 == 1)
                        prodOfMatrices = app.matrixA * app.matrixB;
                        resultMatrix = prodOfMatrices;
                        [app.resRow, app.resCol] = size(prodOfMatrices);
                    end
            else
                msgbox('No Operation Selected. Please Select an Operation.', 'Error: No Operation Detected.')
                app.CalculateButton.Enable = "off";
            end

            % displaying the result
            if ~isempty(app.ResultPanel)
                delete(app.ResultPanel.Children)
            end

            if strcmp(app.ResultPanel.Title, "Result")
                app.ResultPanel.Title = '';
            end
            
            paddingX = 5;
            paddingY = 5;

            numRows3 = app.resRow;
            numCols3 = app.resCol;
            
            panelWidth3 = app.ResultPanel.Position(3);
            panelHeight3 = app.ResultPanel.Position(4);

            fieldWidth3 = (panelWidth3 - (paddingX * (numCols3 - 1))) / numCols3;
            fieldHeight3 = (panelHeight3 - (paddingY * (numRows3 - 1))) / numRows3;
 
            for row3 = 1:numRows3
                for col3 = 1:numCols3
                    field = uieditfield(app.ResultPanel,'numeric', 'Editable', 'off', 'HorizontalAlignment', 'center', 'Value', resultMatrix(row3, col3));

                    fieldX3 = (col3 - 1) * (fieldWidth3 + paddingX);
                    fieldY3 = panelHeight3 - row3 * (fieldHeight3 + paddingY);

                    if row3 == 1
                        fieldY3 = panelHeight3 - row3 * fieldHeight3;
                    end
                    
                    field.Position = [fieldX3, fieldY3, fieldWidth3, fieldHeight3];
                end
            end

            % Clear All Button
            app.ClearResButton.Enable = "on";
            app.ClearAllButton.Enable = 'on';
        end

        % Value changing function: numRowSpinner
        function numRowSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.numRowSp1 = changingValue;
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end

            if ~isempty(app.ResultPanel)
                delete(app.ResultPanel.Children)
            end

            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65';
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.DisplayPanel1.Title = 'Matrix 1';
            app.DisplayPanel2.Title = 'Matrix 2';
            app.ResultPanel.Title = 'Result';
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Value changing function: numColSpinner
        function numColSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.numColSp1 = changingValue;
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end

            if ~isempty(app.ResultPanel)
                delete(app.ResultPanel.Children)
            end

            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65';
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.DisplayPanel1.Title = 'Matrix 1';
            app.DisplayPanel2.Title = 'Matrix 2';
            app.ResultPanel.Title = 'Result';
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Value changing function: numRowSpinner2
        function numRowSpinner2ValueChanging(app, event)
            changingValue = event.Value;
            app.numRowSp2 = changingValue;
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end

            if ~isempty(app.ResultPanel)
                delete(app.ResultPanel.Children)
            end

            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65';
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.DisplayPanel1.Title = 'Matrix 1';
            app.DisplayPanel2.Title = 'Matrix 2';
            app.ResultPanel.Title = 'Result';
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Value changing function: numColSpinner2
        function numColSpinner2ValueChanging(app, event)
            changingValue = event.Value;
            app.numColSp2 = changingValue;
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end

            if ~isempty(app.ResultPanel)
                delete(app.ResultPanel.Children)
            end

            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65';
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.DisplayPanel1.Title = 'Matrix 1';
            app.DisplayPanel2.Title = 'Matrix 2';
            app.ResultPanel.Title = 'Result';
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Value changed function: ADDSwitch
        function ADDSwitchValueChanged(app, event)
            value = app.ADDSwitch.Value;
            if strcmp(value, 'On')
               app.CalculateButton.Enable = "on";
               if ~isempty(app.ResultPanel)
                    delete(app.ResultPanel.Children)
               end
            end

            if (strcmp(app.SUBSwitch.Value, 'On') || strcmp(app.MULSwitch.Value, 'On')) && strcmp(value, 'On') && (~isempty(app.DisplayPanel1) || ~isempty(app.DisplayPanel2))
                app.SUBSwitch.Value = 'Off';
                app.MULSwitch.Value = 'Off';
                app.CalculateButton.Enable = "on";
            end

            if (strcmp(value, 'Off')) && (strcmp(app.SUBSwitch.Value, 'Off') && strcmp(app.MULSwitch.Value, 'Off'))
                app.CalculateButton.Enable = "off";
            end
        end

        % Value changed function: MULSwitch
        function MULSwitchValueChanged(app, event)
            value = app.MULSwitch.Value;
            if strcmp(value, 'On')
               app.CalculateButton.Enable = "on";
               if ~isempty(app.ResultPanel)
                    delete(app.ResultPanel.Children)
               end
            end

            if (strcmp(app.SUBSwitch.Value, 'On') || strcmp(app.ADDSwitch.Value, 'On')) && strcmp(value, 'On') && (~isempty(app.DisplayPanel1) || ~isempty(app.DisplayPanel2))
                app.SUBSwitch.Value = 'Off';
                app.ADDSwitch.Value = 'Off';
                app.CalculateButton.Enable = "on";
            end

            if (strcmp(value, 'Off')) && (strcmp(app.SUBSwitch.Value, 'Off') && strcmp(app.ADDSwitch.Value, 'Off'))
                app.CalculateButton.Enable = "off";
            end
        end

        % Value changed function: SUBSwitch
        function SUBSwitchValueChanged(app, event)
            value = app.SUBSwitch.Value;
            if strcmp(value, 'On')
               app.CalculateButton.Enable = "on";
               if ~isempty(app.ResultPanel)
                    delete(app.ResultPanel.Children)
               end
            end

            if (strcmp(app.MULSwitch.Value, 'On') || strcmp(app.ADDSwitch.Value, 'On')) && strcmp(value, 'On') && (~isempty(app.DisplayPanel1) || ~isempty(app.DisplayPanel2))
                app.MULSwitch.Value = 'Off';
                app.ADDSwitch.Value = 'Off';
                app.CalculateButton.Enable = "on";
            end

            if (strcmp(value, 'Off')) && (strcmp(app.MULSwitch.Value, 'Off') && strcmp(app.ADDSwitch.Value, 'Off'))
                app.CalculateButton.Enable = "off";
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end

            if ~isempty(app.ResultPanel)
                delete(app.ResultPanel.Children)
            end

            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65';
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.DisplayPanel1.Title = 'Matrix 1';
            app.DisplayPanel2.Title = 'Matrix 2';
            app.ResultPanel.Title = 'Result';
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Button pushed function: ClearResButton
        function ClearResButtonPushed(app, event)
            if ~isempty(app.ResultPanel)
                delete(app.ResultPanel.Children)
            end
            
            app.CalculateButton.Enable = "off";
            app.ResultPanel.Title = 'Result';
            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.ClearResButton.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MatrixCalculatorPanel = uipanel(app.UIFigure);
            app.MatrixCalculatorPanel.Title = 'Matrix Calculator';
            app.DisplayPanel1 = uipanel(app.MatrixCalculatorPanel);
            app.DisplayPanel1.Title = 'Matrix 1';
            app.DisplayButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.CalculateButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.DisplayPanel2 = uipanel(app.MatrixCalculatorPanel);
            app.DisplayPanel2.Title = 'Matrix 2';
            app.NumberofRowsSpinnerLabel = uilabel(app.MatrixCalculatorPanel);
            app.NumberofRowsSpinnerLabel.Text = 'Number of Rows';
            app.numRowSpinner = uispinner(app.MatrixCalculatorPanel);
            app.numRowSpinner.ValueChangingFcn = createCallbackFcn(app, @numRowSpinnerValueChanging, true);
            app.numRowSpinner.Limits = [1 5];
            app.numRowSpinner.Value = 1;
            app.NumberofColumnsSpinnerLabel = uilabel(app.MatrixCalculatorPanel);
            app.NumberofColumnsSpinnerLabel.Text = 'Number of Columns';
            app.numColSpinner = uispinner(app.MatrixCalculatorPanel);
            app.numColSpinner.ValueChangingFcn = createCallbackFcn(app, @numColSpinnerValueChanging, true);
            app.numColSpinner.Limits = [1 5];
            app.numColSpinner.Value = 1;
            app.Matrix1Label = uilabel(app.MatrixCalculatorPanel);
            app.Matrix1Label.Text = 'Matrix 1';
            app.Matrix2Label = uilabel(app.MatrixCalculatorPanel);
            app.Matrix2Label.Text = 'Matrix 2';
            app.NumberofRowsSpinnerLabel_2 = uilabel(app.MatrixCalculatorPanel);
            app.NumberofRowsSpinnerLabel_2.Text = 'Number of Rows';
            app.numRowSpinner2 = uispinner(app.MatrixCalculatorPanel);
            app.numRowSpinner2.ValueChangingFcn = createCallbackFcn(app, @numRowSpinner2ValueChanging, true);
            app.numRowSpinner2.Limits = [1 5];
            app.numRowSpinner2.Value = 1;
            app.NumberofColumnsSpinnerLabel_2 = uilabel(app.MatrixCalculatorPanel);
            app.NumberofColumnsSpinnerLabel_2.Text = 'Number of Columns';
            app.numColSpinner2 = uispinner(app.MatrixCalculatorPanel);
            app.numColSpinner2.ValueChangingFcn = createCallbackFcn(app, @numColSpinner2ValueChanging, true);
            app.numColSpinner2.Limits = [1 5];
            app.numColSpinner2.Value = 1;
            app.ADDSwitchLabel = uilabel(app.MatrixCalculatorPanel);
            app.ADDSwitchLabel.Text = 'ADD';
            app.ADDSwitch = uiswitch(app.MatrixCalculatorPanel, 'toggle');
            app.ADDSwitch.ValueChangedFcn = createCallbackFcn(app, @ADDSwitchValueChanged, true);
            app.MULSwitchLabel = uilabel(app.MatrixCalculatorPanel);
            app.MULSwitchLabel.Text = 'MUL';
            app.MULSwitch = uiswitch(app.MatrixCalculatorPanel, 'toggle');
            app.MULSwitch.ValueChangedFcn = createCallbackFcn(app, @MULSwitchValueChanged, true);
            app.SUBLabel = uilabel(app.MatrixCalculatorPanel);
            app.SUBLabel.Text = 'SUB';
            app.SUBSwitch = uiswitch(app.MatrixCalculatorPanel, 'toggle');
            app.SUBSwitch.ValueChangedFcn = createCallbackFcn(app, @SUBSwitchValueChanged, true);
            app.ResultPanel = uipanel(app.MatrixCalculatorPanel);
            app.ResultPanel.Title = 'Result';
            app.ADDLamp = uilamp(app.MatrixCalculatorPanel);
            app.SUBLamp = uilamp(app.MatrixCalculatorPanel);
            app.MULLamp = uilamp(app.MatrixCalculatorPanel);
            app.ClearAllButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.ClearResButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.ClearResButton.ButtonPushedFcn = createCallbackFcn(app, @ClearResButtonPushed, true);
            app.ClearResButton.Text = 'Clear Result';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatrixCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end